classdef hough < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HoughUIFigure  matlab.ui.Figure
        Button         matlab.ui.control.Button
        Button_5       matlab.ui.control.Button
        Button_6       matlab.ui.control.Button
        ButtonGroup    matlab.ui.container.ButtonGroup
        Button_7       matlab.ui.control.RadioButton
        Button_8       matlab.ui.control.RadioButton
        Button_10      matlab.ui.control.RadioButton
        ButtonGroup_2  matlab.ui.container.ButtonGroup
        LoGButton      matlab.ui.control.RadioButton
        PrewittButton  matlab.ui.control.RadioButton
        SobelButton    matlab.ui.control.RadioButton
        RobertsButton  matlab.ui.control.RadioButton
        Button_9       matlab.ui.control.RadioButton
        UIAxes         matlab.ui.control.UIAxes
        UIAxes2        matlab.ui.control.UIAxes
        Button_11      matlab.ui.control.Button
        Label          matlab.ui.control.Label
        TextArea_2     matlab.ui.control.TextArea
        Label_2        matlab.ui.control.Label
        Threshold      matlab.ui.control.NumericEditField
        Label_3        matlab.ui.control.Label
        Threshold2     matlab.ui.control.NumericEditField
        Button_12      matlab.ui.control.Button
        Label_4        matlab.ui.control.Label
        Label_5        matlab.ui.control.Label
        Label_6        matlab.ui.control.Label
        HoughLabel     matlab.ui.control.Label
    end


    properties (Access = public)
        originimg
        margin
        gim
        lb
        ezh
        huofu
        judge=0
        num=0
        threshold%二值化阈值
        threshold2%提取直线阈值
    end

    methods (Access = private)
        
        %log算子
        function  log = log_margin(app,img)
            [m,n]=size(img);
            log=zeros(m,n);
            for i=3:m-2
               	for j=3:n-2
                  log(i,j) = -img(i-2,j)-img(i-1,j-1)-2*img(i-1,j)-img(i-1,j+1)-img(i,j+2)-2*img(i,j-1)+16*img(i,j)-2*img(i,j+1)-img(i,j+2)-img(i+1,j-1)-2*img(i+1,j)-img(i+1,j+1)-img(i+2,j);
               	end
            end            
        end
% function log = log_margin(app,image)
%     log1=[0  0  -1   0  0;
%           0 -1  -2  -1  0;
%          -1 -2  16  -2 -1;
%           0 -1  -2  -1  0;
%           0  0  -1   0  0];
%     log=conv2(image,log1,'same');
% end
        %prewitt算子
        function pre = prewitt_margin(app,f)
            [m,n]=size(f);
            pre=zeros(m,n);
            for i=2:m-1
                for j=2:n-1
                    pre(i,j)=abs(f(i-1,j-1)+f(i,j-1)+f(i+1,j-1)-f(i-1,j+1)-f(i,j+1)-f(i+1,j+1))+abs(f(i+1,j-1)+f(i+1,j)+f(i+1,j+1)-f(i-1,j-1)-f(i-1,j)-f(i-1,j+1));
                end
            end
        end
        
        %sobel算子
        function sob = sobel_margin(app,image)
            [m,n]=size(image);
            sob=zeros(m,n);
            for i=2:m-1
                for j=2:n-1
                    sob(i,j)=abs(image(i-1,j-1)+2*image(i,j-1)+image(i+1,j-1)-image(i-1,j+1)-2*image(i,j+1)-image(i+1,j+1))+abs(image(i+1,j-1)+2*image(i+1,j)+image(i+1,j+1)-image(i-1,j-1)-2*image(i-1,j)-image(i-1,j+1));
                end
            end
        end
        
        %Roberts算子
        function rob = roberts_margin(app,image)
            [m,n]=size(image);
            rob=zeros(m,n);
            for i=2:m-1
                for j=2:n-1
                    rob(i,j)=abs(image(i+1,j+1)-image(i,j))+abs(image(i,j+1)-image(i+1,j));
                end
            end
            
        end
    
        %中值滤波
        function [ x2 ] = median_filter(app,image, m )
            %----------------------------------------------
            %中值滤波
            %输入：
            %image：原图
            %m：模板的大小3*3的模板，m=3
            %输出：
            %img：中值滤波处理后的图像
            %----------------------------------------------
            n = m;
            [ height, width ] = size(image);
            x1 = double(image);
            x2 = x1;
            for i = 1: height-n+1
                for j = 1:width-n+1
                    mb = x1( i:(i+n-1),  j:(j+n-1) );%获取图像中n*n的矩阵
                    mb = mb(:);%将mb变成向量化，变成一个列向量
                    mm = median(mb);%取中间值
                    x2( i+(n-1)/2,  j+(n-1)/2 ) = mm;                    
                end
            end
        end
        
        %均值滤波
        function [smooth] = average_filter(app,image)
            [m,n]=size(image);
            smooth=zeros(m,n);
            for i=2:m-1
               	for j=2:n-1
                  smooth(i,j) = image(i-1,j-1)+image(i-1,j)+image(i-1,j+1)+image(i,j-1)+image(i,j)+image(i,j+1)+image(i+1,j-1)+image(i+1,j)+image(i+1,j+1);
                  smooth(i,j) = smooth(i,j)/9;
               	end
            end
        end
        
        %二值化
        function [binary_img] = binary(app,image,T)
            binary_img = im2uint8(image);% 将均值滤波后的图像转换为uint8类型图像，即图像色彩范围[0,255]
            [m,n]=size(binary_img);
            for i=1:m
               	for j=1:n
                  if binary_img(i,j)>T   % 设置二值化的阈值
                     	binary_img(i,j) = 255;% 超过阈值的设为白点
                  else
                     	binary_img(i,j) = 0;%小于阈值的设为黑点
                  end
               	end
            end
        end
        
        %hough边缘描绘
        function [origin_img] = hough_tram(app,binary_img,origin_img,T)
            angle = 180;
            [m,n]=size(binary_img);
            distance = round(sqrt(m^2+n^2));
            number_ap = zeros(angle,2*distance);
            coordinate = cell(angle,2*distance);
            for i = 1:m
               	for j = 1:n
                  if(binary_img(i,j) == 255)
                     for k = 1:angle
                        p = round(i*cos(pi*k/180)+j*sin(pi*k/180));
                        if(p > 0)
                        number_ap(k,distance+p) = number_ap(k,distance+p)+1;
                        coordinate{k,distance+p} = [coordinate{k,distance+p},[i,j]];
                        else
                        ap = abs(p)+1;	
                        number_ap(k,ap) = number_ap(k,ap)+1;
                        coordinate{k,ap} = [coordinate{k,ap},[i,j]];
                        end
                     end
                  end
               	end
            end
            for i = 1:angle
               	for j = 1:distance*2
                  if(number_ap(i,j)>T)%提取直线的阈值
                     point = coordinate{i,j};%提取对应点坐标
                     [m,n] = size(point);
                     	for k = 1:number_ap(i,j)
                        origin_img(point(2*k-1),point(2*k),1) = 0;
                        origin_img(point(2*k-1),point(2*k),2) = 255;
                        origin_img(point(2*k-1),point(2*k),3) = 0;
                     	end
                  end
               	end
            end
            
        end
        
        %图片保存
        function reserve(app,image,sz)
            path = 'F:/matlab/work/hough transform/';
            prefix = 'hough_';
            format = 'jpg';
            suffix = strcat('.',format);
            imwrite(image,strcat(path,prefix,num2str(sz),suffix));
            msgbox('图像保存成功');
        end
    end
    

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            [filename,filepath]=uigetfile({'*.jpg','*.png'},'选择图片');
            if isequal(filename,0)||isequal(filepath,0)
                errordlg('没有选中的文件','错误');
            else
                file=strcat(filepath,filename);
            end
            im=imread(file);
            app.originimg = im;	
            app.gim=rgb2gray(im);
            app.gim = im2double(app.gim);
            imshow(im,'Parent',app.UIAxes);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case'请选择滤波'
                    warndlg('请选择以下方法','提示');
                case '均值滤波'
                    app.TextArea_2.Value='噪声处理中，请等待……';
                    app.lb=average_filter(app,app.margin);
                case '中值滤波'
                    app.TextArea_2.Value='噪声处理中，请等待……';
                    app.lb=median_filter(app,app.margin,3);
            end
            imshow(app.lb,'Parent',app.UIAxes2);
            app.judge=1;
            if app.judge==1
                app.TextArea_2.Value='噪声处理成功！';
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            switch selectedButton.Text
                case'请选择算子'
                    warndlg('请选择以下方法','提示');
                case 'LoG'
                    app.TextArea_2.Value='边缘检测中，请等待……';
                    app.margin = log_margin(app,app.gim);
                case 'Prewitt'
                    app.TextArea_2.Value='边缘检测中，请等待……';
                    app.margin = prewitt_margin(app,app.gim);
                case 'Sobel'
                    app.TextArea_2.Value='边缘检测中，请等待……';
                    app.margin = sobel_margin(app,app.gim);
                case 'Roberts'
                    app.TextArea_2.Value='边缘检测中，请等待……';
                    app.margin = roberts_margin(app,app.gim);
            end
            imshow(app.margin,'Parent',app.UIAxes2);
            app.judge=1;
            if app.judge==1
                app.TextArea_2.Value='边缘检测成功！';
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            
            app.ezh=binary(app,app.lb,app.threshold);
            imshow(app.ezh,'Parent',app.UIAxes2);
            app.TextArea_2.Value='二值化处理成功！';
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            
            app.TextArea_2.Value='边缘描绘中，请耐心等待……';
            app.huofu=hough_tram(app,app.ezh,app.originimg,app.threshold2);
            imshow(app.huofu,'Parent',app.UIAxes2);
            app.judge=1;
            if app.judge==1
            app.TextArea_2.Value='边缘描绘成功！';
            msgbox('边缘描绘成功！');
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.num=app.num+1;
            reserve(app,app.huofu,app.num);
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            
            
        end

        % Value changed function: Threshold
        function ThresholdValueChanged(app, event)
            app.threshold = app.Threshold.Value;
            
        end

        % Value changed function: Threshold2
        function Threshold2ValueChanged(app, event)
            app.threshold2 = app.Threshold2.Value;
            
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            figure;
            img=getimage(app.UIAxes2);
            imshow(img);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HoughUIFigure = uifigure;
            app.HoughUIFigure.Name = 'Hough变换检测';
            setAutoResize(app, app.HoughUIFigure, true)
            app.Button = uibutton(app.HoughUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '打开文件';
            app.Button_5 = uibutton(app.HoughUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '边缘提取';
            app.Button_6 = uibutton(app.HoughUIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '保存图片';
            app.ButtonGroup = uibuttongroup(app.HoughUIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '噪声处理';
            app.Button_7 = uiradiobutton(app.ButtonGroup);
            app.Button_7.Text = '均值滤波';
            app.Button_8 = uiradiobutton(app.ButtonGroup);
            app.Button_8.Text = '中值滤波';
            app.Button_10 = uiradiobutton(app.ButtonGroup);
            app.Button_10.Text = '请选择滤波';
            app.Button_10.Value = true;
            app.ButtonGroup_2 = uibuttongroup(app.HoughUIFigure);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = '边缘检测';
            app.LoGButton = uiradiobutton(app.ButtonGroup_2);
            app.LoGButton.Text = 'LoG';
            app.PrewittButton = uiradiobutton(app.ButtonGroup_2);
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uiradiobutton(app.ButtonGroup_2);
            app.SobelButton.Text = 'Sobel';
            app.RobertsButton = uiradiobutton(app.ButtonGroup_2);
            app.RobertsButton.Text = 'Roberts';
            app.Button_9 = uiradiobutton(app.ButtonGroup_2);
            app.Button_9.Text = '请选择算子';
            app.Button_9.Value = true;
            app.UIAxes = uiaxes(app.HoughUIFigure);
            title(app.UIAxes, '原图像');
            app.UIAxes2 = uiaxes(app.HoughUIFigure);
            title(app.UIAxes2, '处理结果');
            app.Button_11 = uibutton(app.HoughUIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '二值化处理';
            app.Label = uilabel(app.HoughUIFigure);
            app.Label.Text = '处理信息';
            app.TextArea_2 = uitextarea(app.HoughUIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.Label_2 = uilabel(app.HoughUIFigure);
            app.Label_2.Text = '阈值设置';
            app.Threshold = uieditfield(app.HoughUIFigure, 'numeric');
            app.Threshold.ValueChangedFcn = createCallbackFcn(app, @ThresholdValueChanged, true);
            app.Label_3 = uilabel(app.HoughUIFigure);
            app.Label_3.Text = '阈值设置';
            app.Threshold2 = uieditfield(app.HoughUIFigure, 'numeric');
            app.Threshold2.ValueChangedFcn = createCallbackFcn(app, @Threshold2ValueChanged, true);
            app.Button_12 = uibutton(app.HoughUIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '查看大图';
            app.Label_4 = uilabel(app.HoughUIFigure);
            app.Label_4.Text = '查看处理结果大图';
            app.Label_5 = uilabel(app.HoughUIFigure);
            app.Label_5.Text = '二值化阈值设置';
            app.Label_6 = uilabel(app.HoughUIFigure);
            app.Label_6.Text = '描绘直线的阈值设置';
            app.HoughLabel = uilabel(app.HoughUIFigure);
            app.HoughLabel.Text = 'Hough变换检测边缘特征提取';
        end
    end

    methods (Access = public)

        % Construct app
        function app = hough()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HoughUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HoughUIFigure)
        end
    end
end